classdef aplikacja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CzyMacierzUkladuMusiBycSymetrycznaButtonGroup  matlab.ui.container.ButtonGroup
        NieButton                      matlab.ui.control.RadioButton
        TakButton                      matlab.ui.control.RadioButton
        PodajMaksymalnaWartoscElementowMacierzyEditField  matlab.ui.control.NumericEditField
        PodajmaksymalnwartoelementwmacierzyLabel  matlab.ui.control.Label
        PodajMinimalnaWartoscElementowMacierzyEditField  matlab.ui.control.NumericEditField
        PodajjminimalnwartoelementwmacierzLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        UITable3                       matlab.ui.control.Table
        StatystykiDotyczaceOptymalnejWartosciOmegiLabel  matlab.ui.control.Label
        TypBleduButtonGroup            matlab.ui.container.ButtonGroup
        BladWzglednyButton             matlab.ui.control.RadioButton
        BladBezwzglednyButton          matlab.ui.control.RadioButton
        UITable2                       matlab.ui.control.Table
        UITable                        matlab.ui.control.Table
        GenerujMacierzButton           matlab.ui.control.Button
        PodajNumerNormyEditField       matlab.ui.control.NumericEditField
        PodajnumernormyEditFieldLabel  matlab.ui.control.Label
        PodajPotegeDrugiegoEpsilonaEditField  matlab.ui.control.NumericEditField
        PodajpotgdrugiegoepsilonaEditFieldLabel  matlab.ui.control.Label
        PodajPotegePierwszegoEpsilonaEditField  matlab.ui.control.NumericEditField
        PodajpotgpierwszegoepsilonaLabel  matlab.ui.control.Label
        PodajMaksymalnaLiczbeIteracjiEditField  matlab.ui.control.NumericEditField
        PodajmaksymalnliczbiteracjiEditFieldLabel  matlab.ui.control.Label
        PodajWymiarMacierzyUkladuEditField  matlab.ui.control.NumericEditField
        PodajwymiarmacierzyukaduEditFieldLabel  matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        size  = 0 % dodatni, całkowity skalar reprezentujący wymiar 
        % macierzy układu, czyli także długość wektora mainDiagonal

        maxNumberOfIterations = 0 % maksymalna liczba iteracji, po 
        % przekroczeniu której uznajemy, że dla danej wartości parametru 
        % relaksacji metoda nie jest zbieżna (dodatni skalar)

        epsilon1  = 0 % parametr określający dokładność oznaczony w warunku
        % Gilla jako d1, liczba dodatnia, bliska zeru, ponadto epsilon1 > epsilon2

        epsilon2  = 0 % parametr określający dokładność oznaczony w warunku 
        % Gilla jako d2, liczba dodatnia, bliska zeru, ponadto epsilon1 > epsilon2

        normType  = 0 %  w obliczeniach wykorzystywana jest jedna z norm
        % p-tych Schura, przy czym normType (dodatni skalar lub 'Inf' lub 
        % '-Inf') okresla wartość współczynnika p

        omega % wektor poziomy reprezentujący wszystkie rozważane wartości 
        % parametru relaksacji z przedziału [minOmega, maxOmega]; jeżeli 
        % wygenerowana zostanie wartość parametru relaksacji równa 0, jest
        % ona usuwana z wektora omega; wektor ten ma zatem w domyślnym 
        % przypadku długość równą wartości parametru numberOfOmegas lub 
        % numberOfOmegas - 1, gdy trzeba usunąć 0

        numberOfIterations % wektor poziomy o długości równej wektorowi 
        % omega reprezentujący liczbę iteracji potrzebnych do uzyskania 
        % wyniku o żądanej dokładności dla danej wartości parametru 
        % relaksacji, gdy metoda nie jest zbieżna dla parametru relaksacji
        % stojącego na i-tym miejscu w wektorze omega, to k(i) = NaN

        subDiagonal % wektor reprezentujący poddiagonalę macierzy układu 
        % (elementy znajdujące się bezpośrednio pod przekątną)

        mainDiagonal % wektor reprezentujący diagonalę macierzy układu - 
        % jego długość musi być dokładnie o 1 większa od długości wektorów 
        % subDiagonal i supraDiagonal

        supraDiagonal % wektor reprezentujący naddiagonalę macierzy układu 
        % (elementy znajdujące się bezpośrednio nad przekątną)

        fastestOmegas = []; % wektor przechowujący wartości parametrów 
        % relaksacji, dla których metoda była zbieżna najszybciej, długość
        % wektora jest równa liczbie wygenerowanych przez program macierzy

        absoluteError; % wektor poziomy o długości równej wektorowi omega
        % reprezentujący błąd bezwzględny wyniku uzyskanego metodą SOR przy
        % danej wartości parametru relaksacji

        relativeError; % wektor poziomy o długości równej wektorowi omega 
        % reprezentujący błąd względny wyniku uzyskanego metodą SOR przy 
        % danej wartości parametru relaksacji

        bladButton; % przycisk pozwalający na wybór błędu, który zostanie
        % przedstawiony na wykresie

        hasToBeSymmetricButton; % przycisk pozwalający na wybór, czy
        % wygenerowana macierz musi być symetryczna, czy niekoniecznie

        smallestErrorOmegas = []; % wektor przechowujący wartości parametrów 
        % relaksacji, dla których uzyskany metodą SOR wynik charakteryzował 
        % się najmniejszym błędem względnym

        accurateResult; % wektor poziomy reprezentujący dokładne rozwiązanie
        % rozważanego układu równań liniowych, uzyskany metodą wbudowaną w 
        % MATLABA (wykorzystywana jest tu metoda GEPP)

        minValue = 0; % skalar określający dolny kraniec przedziału, z 
        % którego losowane będą elementy macierzy

        maxValue = 0; % skalar określający górny kraniec przedziału, z 
        % którego losowane będą elementy macierzy
        
        hasToBeSymmetric = true; % logiczny skalar, decydujący o tym, czy
        % wygenerowana macierz musi być symetryczna (wówczas wartość tego 
        % parametru jest ustawiona na true)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerujMacierzButton
        function GenerujMacierzButtonPushed(app, event)
            [app.subDiagonal, app.mainDiagonal, app.supraDiagonal] = generator(app.size, 1.2, app.hasToBeSymmetric, app.minValue, app.maxValue);
            b = (app.maxValue - app.minValue) * rand(1,app.size) + app.minValue;
            [app.omega, app.numberOfIterations, app.absoluteError, app.relativeError, fastestOmega, smallestErrorOmega, app.accurateResult] = dependenceOfTheNumberOfIterationsOnOmega(app.subDiagonal, app.mainDiagonal, app.supraDiagonal, b, 0, 2, 1000, zeros(1,app.size), app.maxNumberOfIterations, app.epsilon1, app.epsilon2, app.normType, true);

            plot(app.UIAxes, [app.omega], [app.numberOfIterations])

            if app.bladButton == app.BladWzglednyButton
                semilogy(app.UIAxes2, [app.omega], [app.relativeError])
                app.UIAxes2.Title.String = "Zależność błędu względnego od parametru relaksacji";
                app.UIAxes2.YLabel.String = "Błąd względny";
            else 
                semilogy(app.UIAxes2, [app.omega], [app.absoluteError])
                app.UIAxes2.Title.String = "Zależność błędu bezwzględnego od parametru relaksacji";
                app.UIAxes2.YLabel.String = "Błąd bezwzględny";
            end

            if fastestOmega ~= 0.002
                app.fastestOmegas = [app.fastestOmegas, fastestOmega];
            end
            if smallestErrorOmega ~= 0.002
                app.smallestErrorOmegas = [app.smallestErrorOmegas, smallestErrorOmega];
            end
            

            app.UITable.Data = [[app.subDiagonal, NaN]', app.mainDiagonal', [app.supraDiagonal, NaN]', b', app.accurateResult'];
            app.UITable2.Data = [fastestOmega, min(app.fastestOmegas), mean(app.fastestOmegas), median(app.fastestOmegas), (max(app.fastestOmegas)), length(app.fastestOmegas)];
            app.UITable3.Data = [smallestErrorOmega, min(app.smallestErrorOmegas), mean(app.smallestErrorOmegas), median(app.smallestErrorOmegas), (max(app.smallestErrorOmegas)), length(app.smallestErrorOmegas)];
        end

        % Value changed function: PodajWymiarMacierzyUkladuEditField
        function PodajWymiarMacierzyUkladuEditFieldValueChanged(app, event)
            app.size = app.PodajWymiarMacierzyUkladuEditField.Value;
        end

        % Value changed function: PodajMaksymalnaLiczbeIteracjiEditField
        function PodajMaksymalnaLiczbeIteracjiEditFieldValueChanged(app, event)
            app.maxNumberOfIterations = app.PodajMaksymalnaLiczbeIteracjiEditField.Value; 
        end

        % Value changed function: PodajPotegePierwszegoEpsilonaEditField
        function PodajPotegePierwszegoEpsilonaEditFieldValueChanged(app, event)
            app.epsilon1 = power(10, app.PodajPotegePierwszegoEpsilonaEditField.Value);  
        end

        % Value changed function: PodajPotegeDrugiegoEpsilonaEditField
        function PodajPotegeDrugiegoEpsilonaEditFieldValueChanged(app, event)
            app.epsilon2 = power(10, app.PodajPotegeDrugiegoEpsilonaEditField.Value);         
        end

        % Value changed function: PodajNumerNormyEditField
        function PodajNumerNormyEditFieldValueChanged(app, event)
            app.normType = app.PodajNumerNormyEditField.Value;           
        end

        % Selection changed function: TypBleduButtonGroup
        function TypBleduButtonGroupSelectionChanged(app, event)
            app.bladButton = app.TypBleduButtonGroup.SelectedObject;
            if app.bladButton == app.BladWzglednyButton
                semilogy(app.UIAxes2, [app.omega], [app.relativeError])
                app.UIAxes2.Title.String = "Zależność błędu względnego od parametru relaksacji";
                app.UIAxes2.YLabel.String = "Błąd względny";
            else 
                semilogy(app.UIAxes2, [app.omega], [app.absoluteError])
                app.UIAxes2.Title.String = "Zależność błędu bezwzględnego od parametru relaksacji";
                app.UIAxes2.YLabel.String = "Błąd bezwzględny";
            end         
        end

        % Value changed function: 
        % PodajMinimalnaWartoscElementowMacierzyEditField
        function PodajMinimalnaWartoscElementowMacierzyEditFieldValueChanged(app, event)
            app.minValue = app.PodajMinimalnaWartoscElementowMacierzyEditField.Value;           
        end

        % Value changed function: 
        % PodajMaksymalnaWartoscElementowMacierzyEditField
        function PodajMaksymalnaWartoscElementowMacierzyEditFieldValueChanged(app, event)
            app.maxValue = app.PodajMaksymalnaWartoscElementowMacierzyEditField.Value;            
        end

        % Selection changed function: 
        % CzyMacierzUkladuMusiBycSymetrycznaButtonGroup
        function CzyMacierzUkladuMusiBycSymetrycznaButtonGroupSelectionChanged(app, event)
            app.hasToBeSymmetricButton = app.CzyMacierzUkladuMusiBycSymetrycznaButtonGroup.SelectedObject;
            
            if app.hasToBeSymmetricButton == app.TakButton
                app.hasToBeSymmetric = true;
            else
                app.hasToBeSymmetric = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Zależność liczby iteracji od parametru relaksacji')
            xlabel(app.UIAxes, 'Parametr relaksacji')
            ylabel(app.UIAxes, 'Liczba iteracji')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 2];
            app.UIAxes.YLim = [0 Inf];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Zależność błędu bezwzględnego od parametru relaksacji')
            xlabel(app.UIAxes2, 'Parametr relaksacji')
            ylabel(app.UIAxes2, 'Błąd bezwzględny')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 2];
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.UIAxes2.XTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'; '1.2'; '1.4'; '1.6'; '1.8'; '2'};
            app.PodajwymiarmacierzyukaduEditFieldLabel = uilabel(app.UIFigure);
            app.PodajwymiarmacierzyukaduEditFieldLabel.Text = 'Podaj wymiar macierzy układu';
            app.PodajWymiarMacierzyUkladuEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajWymiarMacierzyUkladuEditField.ValueChangedFcn = createCallbackFcn(app, @PodajWymiarMacierzyUkladuEditFieldValueChanged, true);
            app.PodajmaksymalnliczbiteracjiEditFieldLabel = uilabel(app.UIFigure);
            app.PodajmaksymalnliczbiteracjiEditFieldLabel.Text = 'Podaj maksymalną liczbę iteracji';
            app.PodajMaksymalnaLiczbeIteracjiEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajMaksymalnaLiczbeIteracjiEditField.ValueChangedFcn = createCallbackFcn(app, @PodajMaksymalnaLiczbeIteracjiEditFieldValueChanged, true);
            app.PodajpotgpierwszegoepsilonaLabel = uilabel(app.UIFigure);
            app.PodajpotgpierwszegoepsilonaLabel.Text = 'Podaj potęgę pierwszego epsilona';
            app.PodajPotegePierwszegoEpsilonaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajPotegePierwszegoEpsilonaEditField.ValueChangedFcn = createCallbackFcn(app, @PodajPotegePierwszegoEpsilonaEditFieldValueChanged, true);
            app.PodajpotgdrugiegoepsilonaEditFieldLabel = uilabel(app.UIFigure);
            app.PodajpotgdrugiegoepsilonaEditFieldLabel.Text = 'Podaj potęgę drugiego epsilona';
            app.PodajPotegeDrugiegoEpsilonaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajPotegeDrugiegoEpsilonaEditField.ValueChangedFcn = createCallbackFcn(app, @PodajPotegeDrugiegoEpsilonaEditFieldValueChanged, true);
            app.PodajnumernormyEditFieldLabel = uilabel(app.UIFigure);
            app.PodajnumernormyEditFieldLabel.Text = 'Podaj numer normy';
            app.PodajNumerNormyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajNumerNormyEditField.ValueChangedFcn = createCallbackFcn(app, @PodajNumerNormyEditFieldValueChanged, true);
            app.GenerujMacierzButton = uibutton(app.UIFigure, 'push');
            app.GenerujMacierzButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujMacierzButtonPushed, true);
            app.GenerujMacierzButton.Text = 'Generuj macierz';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Poddiagonala'; 'Diagonala'; 'Naddiagonala'; 'Wektor wyrazów wolnych'; 'Dokładny wynik'};
            app.UITable.ColumnRearrangeable = 'on';
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Aktualna wartość'; 'Minimum'; 'Średnia'; 'Mediana'; 'Maksimum'; 'Liczba wygenerowanych macierzy'};
            app.UITable2.RowName = {};
            app.TypBleduButtonGroup = uibuttongroup(app.UIFigure);
            app.TypBleduButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypBleduButtonGroupSelectionChanged, true);
            app.TypBleduButtonGroup.Title = 'Typ błędu';
            app.BladBezwzglednyButton = uiradiobutton(app.TypBleduButtonGroup);
            app.BladBezwzglednyButton.Text = 'Błąd bezwzględny';
            app.BladBezwzglednyButton.Value = true;
            app.BladWzglednyButton = uiradiobutton(app.TypBleduButtonGroup);
            app.BladWzglednyButton.Text = 'Błąd względny';
            app.StatystykiDotyczaceOptymalnejWartosciOmegiLabel = uilabel(app.UIFigure);
            app.StatystykiDotyczaceOptymalnejWartosciOmegiLabel.Text = 'Statystyki dotyczące optymalnego parametru relaksacji pod kątem liczby iteracji';
            app.UITable3 = uitable(app.UIFigure);
            app.UITable3.ColumnName = {'Aktualna wartość'; 'Minimum'; 'Średnia'; 'Mediana'; 'Maksimum'; 'Liczba wygenerowanych macierzy'};
            app.UITable3.RowName = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Statystyki dotyczące optymalnego parametru relaksacji pod kątem błędu względnego';
            app.PodajjminimalnwartoelementwmacierzLabel = uilabel(app.UIFigure);
            app.PodajjminimalnwartoelementwmacierzLabel.Text = 'Podaj minimalną wartość elementów macierzy';
            app.PodajMinimalnaWartoscElementowMacierzyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajMinimalnaWartoscElementowMacierzyEditField.ValueChangedFcn = createCallbackFcn(app, @PodajMinimalnaWartoscElementowMacierzyEditFieldValueChanged, true);
            app.PodajmaksymalnwartoelementwmacierzyLabel = uilabel(app.UIFigure);
            app.PodajmaksymalnwartoelementwmacierzyLabel.Text = 'Podaj maksymalną wartość elementów macierzy';
            app.PodajMaksymalnaWartoscElementowMacierzyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PodajMaksymalnaWartoscElementowMacierzyEditField.ValueChangedFcn = createCallbackFcn(app, @PodajMaksymalnaWartoscElementowMacierzyEditFieldValueChanged, true);
            app.CzyMacierzUkladuMusiBycSymetrycznaButtonGroup = uibuttongroup(app.UIFigure);
            app.CzyMacierzUkladuMusiBycSymetrycznaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CzyMacierzUkladuMusiBycSymetrycznaButtonGroupSelectionChanged, true);
            app.CzyMacierzUkladuMusiBycSymetrycznaButtonGroup.Title = 'Czy macierz układu musi być symetryczna?';
            app.TakButton = uiradiobutton(app.CzyMacierzUkladuMusiBycSymetrycznaButtonGroup);
            app.TakButton.Text = 'Tak';
            app.TakButton.Value = true;
            app.NieButton = uiradiobutton(app.CzyMacierzUkladuMusiBycSymetrycznaButtonGroup);
            app.NieButton.Text = 'Nie';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikacja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end