classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure1                      matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        WartoNmaxSlider              matlab.ui.control.Slider
        WartoNmaxSliderLabel         matlab.ui.control.Label
        MaksymalnawartoxSlider       matlab.ui.control.Slider
        MaksymalnawartoxSliderLabel  matlab.ui.control.Label
        ZatwierdzmianyButton         matlab.ui.control.Button
        MinimalnawartoxLabel         matlab.ui.control.Label
        MinimumX                     matlab.ui.control.Slider
        WybrwykresuButtonGroup       matlab.ui.container.ButtonGroup
        WykresprzyblionyButton       matlab.ui.control.RadioButton
        WykresdokadnyButton          matlab.ui.control.RadioButton
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        minX =-10 % minimalna wartość wektora X (domyślnie -10)
        maxX = 10 % maksymalna wartość wektora X (domyślnie 10)
        X % wektor wartości na osi OX
        Nmax = 9 % liczba wyrazów rozwinięcia funkcji w szereg (domyślnie 9)
        selectedButton % przycisk wybrany na ZatwierdzmianyButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ZatwierdzmianyButton
        function ZatwierdzmianyButtonPushed(app, event)
            app.X = app.minX:pi/100:app.maxX; % utworzenie wektora X
            plot(app.UIAxes2, [app.X], [abs((szeregSin(app.X, app.Nmax) - func(app.X))./func(app.X))]); 
            % utworzenie wykresu błędu względnego przybliżenia funkcji
            % sin(x) szeregiem potęgowym w zależności od x
            app.UITable.Data = szeregBlad(app.X, app.Nmax);
            % stworzenie tablicy Z otrzymanej z funkcji szeregBlad
            if app.selectedButton == app.WykresprzyblionyButton
                plot(app.UIAxes, [app.X], [szeregSin(app.X, app.Nmax)]); 
                % rysowanie przybliżonego wykresu funkcji sin(x)
                app.UIAxes.Title.String = "Przybliżony wykres funkcji sin(x)";
            else
                plot(app.UIAxes, [app.X], [func(app.X)]);
                % rysowanie dokładnego wykresu funkcji sin(x)
                app.UIAxes.Title.String = "Dokładny wykres funkcji sin(x)";
            end
        end

        % Value changing function: MinimumX
        function MinimumXValueChanging(app, event)
            changingValue = event.Value;
            app.minX = changingValue;
        end

        % Value changing function: MaksymalnawartoxSlider
        function MaksymalnawartoxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.maxX = changingValue;
        end

        % Selection changed function: WybrwykresuButtonGroup
        function WybrwykresuButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.WybrwykresuButtonGroup.SelectedObject;
        end

        % Value changing function: WartoNmaxSlider
        function WartoNmaxSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Nmax = floor(changingValue);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure1 = uifigure('Visible', 'off');
            app.Figure1.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.Figure1);
            title(app.UIAxes, {'Dokładny wykres funkcji sin(x)'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.Figure1);
            title(app.UIAxes2, {'Wykres błędu względnego przybliżenia '; 'funkcji sin(x)'})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.WybrwykresuButtonGroup = uibuttongroup(app.Figure1);
            app.WybrwykresuButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WybrwykresuButtonGroupSelectionChanged, true);
            app.WybrwykresuButtonGroup.Title = 'Wybór wykresu';
            app.WykresdokadnyButton = uiradiobutton(app.WybrwykresuButtonGroup);
            app.WykresdokadnyButton.Text = 'Wykres dokładny';
            app.WykresdokadnyButton.Value = true;
            app.WykresprzyblionyButton = uiradiobutton(app.WybrwykresuButtonGroup);
            app.WykresprzyblionyButton.Text = 'Wykres przybliżony';
            app.MinimumX = uislider(app.Figure1);
            app.MinimumX.Limits = [-100 0];
            app.MinimumX.ValueChangingFcn = createCallbackFcn(app, @MinimumXValueChanging, true);
            app.MinimumX.Value = -10;
            app.MinimalnawartoxLabel = uilabel(app.Figure1);
            app.MinimalnawartoxLabel.Text = 'Minimalna wartość x :';
            app.ZatwierdzmianyButton = uibutton(app.Figure1, 'push');
            app.ZatwierdzmianyButton.ButtonPushedFcn = createCallbackFcn(app, @ZatwierdzmianyButtonPushed, true);
            app.ZatwierdzmianyButton.Text = 'Zatwierdź zmiany';
            app.MaksymalnawartoxSliderLabel = uilabel(app.Figure1);
            app.MaksymalnawartoxSliderLabel.Text = 'Maksymalna wartość x:';
            app.MaksymalnawartoxSlider = uislider(app.Figure1);
            app.MaksymalnawartoxSlider.ValueChangingFcn = createCallbackFcn(app, @MaksymalnawartoxSliderValueChanging, true);
            app.MaksymalnawartoxSlider.Value = 10;
            app.WartoNmaxSliderLabel = uilabel(app.Figure1);
            app.WartoNmaxSliderLabel.Text = 'Wartość Nmax';
            app.WartoNmaxSlider = uislider(app.Figure1);
            app.WartoNmaxSlider.ValueChangingFcn = createCallbackFcn(app, @WartoNmaxSliderValueChanging, true);
            app.WartoNmaxSlider.Value = 9;
            app.UITable = uitable(app.Figure1);
            app.UITable.ColumnName = {'N'; 'x'; 'yaprox'; 'yreal'; 'blad'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.Figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure1)
        end
    end
end